classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        P_BESSEditField                 matlab.ui.control.EditField
        P_BESSEditFieldLabel            matlab.ui.control.Label
        BESSBreakerSwitchA              matlab.ui.control.Switch
        BESSBreakerSwitchALabel         matlab.ui.control.Label
        SOC_BESSEditField               matlab.ui.control.EditField
        SOC_BESSEditFieldLabel          matlab.ui.control.Label
        PV_BreakerStatusEditField_3     matlab.ui.control.EditField
        PV_BreakerStatusEditFieldLabel_3  matlab.ui.control.Label
        FCBreakerSwitchA                matlab.ui.control.Switch
        FCBreakerSwitchALabel           matlab.ui.control.Label
        P_FVEditField                   matlab.ui.control.EditField
        P_FVEditFieldLabel              matlab.ui.control.Label
        PV_BreakerStatusEditField_2     matlab.ui.control.EditField
        PV_BreakerStatusEditFieldLabel_2  matlab.ui.control.Label
        Image4_3                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        PVBreakerSwitchA                matlab.ui.control.Switch
        PVBreakerSwitchA_2Label         matlab.ui.control.Label
        CurtailmentSet_PVSlider         matlab.ui.control.Slider
        CurtailmentSet_PVSliderLabel    matlab.ui.control.Label
        CurtailmentEnableBreakerSwitchA  matlab.ui.control.Switch
        CurtailmentEnableBreakerSwitchALabel  matlab.ui.control.Label
        PV_BreakerStatusEditField       matlab.ui.control.EditField
        PV_BreakerStatusEditFieldLabel  matlab.ui.control.Label
        I_PVEditField                   matlab.ui.control.EditField
        I_PVEditFieldLabel              matlab.ui.control.Label
        Irradiance_PVEditField          matlab.ui.control.EditField
        Irradiance_PVEditFieldLabel     matlab.ui.control.Label
        V_PVEditField                   matlab.ui.control.EditField
        V_PVEditFieldLabel              matlab.ui.control.Label
        duty_cycle_PVEditField          matlab.ui.control.EditField
        duty_cycle_PVEditFieldLabel     matlab.ui.control.Label
        P_PVEditField                   matlab.ui.control.EditField
        P_PVEditFieldLabel              matlab.ui.control.Label
        DroopQG2Slider                  matlab.ui.control.Slider
        DroopQG2SliderLabel             matlab.ui.control.Label
        DroopPG2Slider                  matlab.ui.control.Slider
        DroopPG2SliderLabel             matlab.ui.control.Label
        QrefG2Slider                    matlab.ui.control.Slider
        QrefG2SliderLabel               matlab.ui.control.Label
        PrefG2Slider                    matlab.ui.control.Slider
        PrefG2SliderLabel               matlab.ui.control.Label
        GEN2BreakerSwitchA              matlab.ui.control.Switch
        GEN2BreakerSwitchALabel         matlab.ui.control.Label
        G2_BreakerStatusEditField       matlab.ui.control.EditField
        G2_BreakerStatusEditFieldLabel  matlab.ui.control.Label
        V_gen2_puEditField              matlab.ui.control.EditField
        V_gen2_puEditFieldLabel         matlab.ui.control.Label
        freq_gen2_puEditField           matlab.ui.control.EditField
        freq_gen2_puEditFieldLabel      matlab.ui.control.Label
        P_gen2_puEditField              matlab.ui.control.EditField
        P_gen2_puEditFieldLabel         matlab.ui.control.Label
        Q_gen2_puEditField              matlab.ui.control.EditField
        Q_gen2_puEditFieldLabel         matlab.ui.control.Label
        P_gen2EditField                 matlab.ui.control.EditField
        P_gen2EditFieldLabel            matlab.ui.control.Label
        DroopQG1Slider                  matlab.ui.control.Slider
        DroopQG1SliderLabel             matlab.ui.control.Label
        DroopPG1Slider                  matlab.ui.control.Slider
        DroopPG1SliderLabel             matlab.ui.control.Label
        QrefG1Slider                    matlab.ui.control.Slider
        QrefG1SliderLabel               matlab.ui.control.Label
        PrefG1Slider                    matlab.ui.control.Slider
        PrefG1SliderLabel               matlab.ui.control.Label
        PCCBreakerSwitch_2              matlab.ui.control.Switch
        PCCBreakerSwitch_2Label         matlab.ui.control.Label
        G1_BreakerStatusEditField       matlab.ui.control.EditField
        G1_BreakerStatusEditFieldLabel  matlab.ui.control.Label
        V_gen1_puEditField              matlab.ui.control.EditField
        V_gen1_puEditFieldLabel         matlab.ui.control.Label
        freq_gen1_puEditField           matlab.ui.control.EditField
        freq_gen1_puEditFieldLabel      matlab.ui.control.Label
        P_gen1_puEditField              matlab.ui.control.EditField
        P_gen1_puEditFieldLabel         matlab.ui.control.Label
        Q_gen1_puEditField              matlab.ui.control.EditField
        Q_gen1_puEditFieldLabel         matlab.ui.control.Label
        P_gen1EditField                 matlab.ui.control.EditField
        P_gen1EditFieldLabel            matlab.ui.control.Label
        GEN1BreakerSwitchA              matlab.ui.control.Switch
        GEN1BreakerSwitchALabel         matlab.ui.control.Label
        GridStatusEditField             matlab.ui.control.EditField
        GridStatusEditFieldLabel        matlab.ui.control.Label
        QGridEditField                  matlab.ui.control.EditField
        QGridEditFieldLabel             matlab.ui.control.Label
        PGridEditField                  matlab.ui.control.EditField
        PGridEditFieldLabel             matlab.ui.control.Label
        phiMicroGridEditField           matlab.ui.control.EditField
        phiMicroGridEditFieldLabel      matlab.ui.control.Label
        phiUtilityGridEditField         matlab.ui.control.EditField
        phiUtilityGridEditFieldLabel    matlab.ui.control.Label
        iMicrogridEditField_2           matlab.ui.control.EditField
        iMicrogridEditField_2Label      matlab.ui.control.Label
        iUtilityGridEditField           matlab.ui.control.EditField
        iUtilityGridEditFieldLabel      matlab.ui.control.Label
        vMicroGridEditField             matlab.ui.control.EditField
        vMicroGridEditFieldLabel        matlab.ui.control.Label
        vUtilityGridEditField           matlab.ui.control.EditField
        vUtilityGridEditFieldLabel      matlab.ui.control.Label
        DisconnectFromIEDsButton        matlab.ui.control.Button
        ConnectToIEDsButton             matlab.ui.control.Button
    end

    properties (Access = private)
        pyiec
        isConnected logical = false

        % Array of device structs (add devices here)
        devices struct = struct([])

        % UI map struct (fields point to editfields/sliders)
        uiMap struct = struct()

        pollTimer = []
        pollPeriod double = 1.0

        inSwitchCallback logical = false
        iecBusy logical = false
    end

    % ==================== PRIVATE HELPERS ====================
    methods (Access = private)

        function startupFcn(app)
            app.initDevices();
            app.uiMap = app.buildUiMap();

            [ok, msg] = app.importIecModule();
            if ~ok
                uialert(app.UIFigure, msg, "IEC61850");
                return;
            end

            app.ensureTimer();

            app.DisconnectFromIEDsButton.Enable = "off";
            app.ConnectToIEDsButton.Enable = "on";

            % Set switch defaults without triggering callbacks
            app.inSwitchCallback = true;
            try
                app.PCCBreakerSwitch_2.Value = "Disconnect From Utility Grid";
            catch
            end
            try
                app.GEN1BreakerSwitchA.Value = "Disconnect From Bus";
            catch
            end
            try
                app.GEN2BreakerSwitchA.Value = "Disconnect From Bus";
            catch
            end

            % Make it read-only for user, but still allow programmatic updates
            try
                app.DroopPG2Slider.Editable = "off";
                app.DroopQG2Slider.Editable = "off";
            catch
                % if your MATLAB doesn't have Editable, keep Enable on and just ignore callbacks
                try
                    app.DroopPG2Slider.Enable = "on";
                    app.DroopQG2Slider.Enable = "on";
                catch
                end
            end
           app.inSwitchCallback = false;

            % Clear displays
            try app.GridStatusEditField.Value = ""; catch, end
            try app.G1_BreakerStatusEditField.Value = ""; catch, end
        end

        function initDevices(app)
            % Add new IEDs by copy/paste a struct block.
            app.devices = [
                struct( ...
                "name","PCC1", ...
                "host","192.168.1.201", ...
                "port",int32(102), ...
                "LD","PCC1PCC1LD0", ...
                "GGIO","GGIO1", ...
                "con",[], ...
                "connected",false ...
                )
                struct( ...
                "name","GEN1", ...
                "host","192.168.1.202", ...
                "port",int32(102), ...
                "LD","GEN1GEN1LD0", ...   % your addressing LD
                "GGIO","GGIO1", ...
                "con",[], ...
                "connected",false ...
                )
                struct( ...
                "name","GEN2", ...
                "host","192.168.1.203", ...
                "port",int32(102), ...
                "LD","GEN2GEN2LD0", ...   % your addressing LD
                "GGIO","GGIO1", ...
                "con",[], ...
                "connected",false ...
                )
                struct( ...
                "name","PV", ...
                "host","192.168.1.204", ...
                "port",int32(102), ...
                "LD","PVPVLD0", ...   % your addressing LD
                "GGIO","GGIO1", ...
                "con",[], ...
                "connected",false ...
                )                
                ];
        end

        function m = buildUiMap(app)
            m = struct();

            % PCC1 (utility grid) readbacks
            m.PCC1 = struct();
            m.PCC1.AnIn = containers.Map('KeyType','double','ValueType','any');
            m.PCC1.AnIn(1) = app.vUtilityGridEditField;
            m.PCC1.AnIn(2) = app.vMicroGridEditField;
            m.PCC1.AnIn(3) = app.iUtilityGridEditField;
            m.PCC1.AnIn(4) = app.iMicrogridEditField_2;
            m.PCC1.AnIn(5) = app.phiUtilityGridEditField;
            m.PCC1.AnIn(6) = app.phiMicroGridEditField;
            m.PCC1.AnIn(7) = app.PGridEditField;
            m.PCC1.AnIn(8) = app.QGridEditField;
            m.PCC1.GridStatus = app.GridStatusEditField;
            m.PCC1.BreakerSwitch = app.PCCBreakerSwitch_2;

            % GEN1 readbacks (AnIn1..5 in your server mapping screenshot)
            m.GEN1 = struct();
            m.GEN1.AnIn = containers.Map('KeyType','double','ValueType','any');
            m.GEN1.AnIn(1) = app.P_gen1EditField;         % AnIn1
            m.GEN1.AnIn(2) = app.P_gen1_puEditField;      % AnIn2
            m.GEN1.AnIn(3) = app.V_gen1_puEditField;      % AnIn3
            m.GEN1.AnIn(4) = app.Q_gen1_puEditField;      % AnIn4
            m.GEN1.AnIn(5) = app.freq_gen1_puEditField;   % AnIn5

            m.GEN1.BreakerStatus = app.G1_BreakerStatusEditField;
            m.GEN1.BreakerSwitch = app.GEN1BreakerSwitchA;

            % GEN1 sliders (write-only setpoints)
            m.GEN1.Sliders = struct();
            m.GEN1.Sliders.Pref  = app.PrefG1Slider;
            m.GEN1.Sliders.Qref  = app.QrefG1Slider;
            m.GEN1.Sliders.DroopP = app.DroopPG1Slider;
            m.GEN1.Sliders.DroopQ = app.DroopQG1Slider;

            m.GEN2 = struct();
            m.GEN2.AnIn = containers.Map('KeyType','double','ValueType','any');
            m.GEN2.AnIn(1) = app.P_gen2EditField;         % AnIn1
            m.GEN2.AnIn(2) = app.P_gen2_puEditField;      % AnIn2
            m.GEN2.AnIn(3) = app.V_gen2_puEditField;      % AnIn3
            m.GEN2.AnIn(4) = app.Q_gen2_puEditField;      % AnIn4
            m.GEN2.AnIn(5) = app.freq_gen2_puEditField;   % AnIn5

            m.GEN2.BreakerStatus = app.G2_BreakerStatusEditField;
            m.GEN2.BreakerSwitch = app.GEN2BreakerSwitchA;

            m.GEN2.Sliders = struct();
            m.GEN2.Sliders.Pref  = app.PrefG2Slider;
            m.GEN2.Sliders.Qref  = app.QrefG2Slider;

            % GEN2 readback sliders (these are NOT setpoints, they display AnIn6/AnIn7)
            m.GEN2.ReadbackSliders = struct();
            m.GEN2.ReadbackSliders.DroopP = app.DroopPG2Slider;   % shows AnIn6
            m.GEN2.ReadbackSliders.DroopQ = app.DroopQG2Slider;   % shows AnIn7

            m.PV = struct();
            m.PV.AnIn = containers.Map('KeyType','double','ValueType','any');
            m.PV.AnIn(1) = app.P_PVEditField;         % AnIn1
            m.PV.AnIn(2) = app.V_PVEditField;      % AnIn2
            m.PV.AnIn(3) = app.I_PVEditField;      % AnIn3
            m.PV.AnIn(4) = app.duty_cycle_PVEditField;      % AnIn4
            m.PV.AnIn(5) = app.Irradiance_PVEditField;   % AnIn5

            m.PV.BreakerStatus = app.PV_BreakerStatusEditField;
            m.PV.BreakerSwitch = app.PVBreakerSwitchA;
            m.PV.CurtailSwitch = app.CurtailmentEnableBreakerSwitchA;

            m.PV.Sliders = struct();
            m.PV.Sliders.CurtailmentSet_PV  = app.CurtailmentSet_PVSlider;
        end

        function ensureTimer(app)
            try
                if isempty(app.pollTimer)
                    % create below
                elseif ~isa(app.pollTimer,'timer')
                    try delete(app.pollTimer); catch, end
                    app.pollTimer = [];
                elseif ~isvalid(app.pollTimer)
                    app.pollTimer = [];
                end
            catch
                app.pollTimer = [];
            end

            if isempty(app.pollTimer)
                app.pollTimer = timer( ...
                    "ExecutionMode","fixedSpacing", ...
                    "Period", app.pollPeriod, ...
                    "BusyMode","drop", ...
                    "TimerFcn", @(~,~)app.pollOnce() );
            else
                try app.pollTimer.Period = app.pollPeriod; catch, end
            end
        end

        function [ok, msg] = importIecModule(app)
            ok = false; msg = "";
            try
                app.pyiec = py.importlib.import_module("pyiec61850");
                ok = true; return;
            catch ME1
                try
                    app.pyiec = py.importlib.import_module("iec61850");
                    ok = true; return;
                catch ME2
                    app.pyiec = [];
                    msg = "Python import failed. Fix MATLAB Python env." + newline + ...
                        "Run in MATLAB:" + newline + ...
                        "  pyenv" + newline + ...
                        "  pyenv('Version','/path/to/python3')" + newline + ...
                        "Then:" + newline + ...
                        "  py.importlib.import_module('pyiec61850')" + newline + ...
                        "Error1: " + string(ME1.message) + newline + ...
                        "Error2: " + string(ME2.message);
                end
            end
        end

        function [con, d] = getCon(app, devName)
            con = [];
            d = struct();

            if isempty(app.devices)
                return;
            end

            names = string({app.devices.name});   % normalize
            idx = find(names == string(devName), 1);

            if isempty(idx)
                % debug print (optional)
                disp("getCon: device not found: " + string(devName) + ...
                    " (available: " + strjoin(names, ", ") + ")");
                return;
            end

            d = app.devices(idx);

            if isfield(d,"connected") && d.connected && isfield(d,"con") && ~isempty(d.con)
                con = d.con;
            end
        end

        function d = getDevice(app, devName)
            d = struct();

            if isempty(app.devices)
                return;
            end

            names = string({app.devices.name});   % normalize
            idx = find(names == string(devName), 1);

            if isempty(idx)
                % debug print (optional)
                disp("getDevice: device not found: " + string(devName) + ...
                    " (available: " + strjoin(names, ", ") + ")");
                return;
            end

            d = app.devices(idx);
        end


        function ref = refAnIn(app, devName, idx)
            d = app.getDevice(devName);
            ref = sprintf('%s/%s.AnIn%d.mag.f', char(d.LD), char(d.GGIO), idx);
        end

        function ref = refSPCSO_stVal(app, devName, spcsoName)
            d = app.getDevice(devName);
            ref = sprintf('%s/%s.%s.stVal', char(d.LD), char(d.GGIO), char(spcsoName));
        end

        function ref = refControlObject(app, devName, lnName)
            d = app.getDevice(devName);
            ref = sprintf('%s/%s.%s', char(d.LD), char(d.GGIO), char(lnName));
        end

        function ref = refGEN1_DO_setMag(app, doIdx)
            d = app.getDevice("GEN1");
            ref = sprintf('%s/%s.DO%d.setMag.f', char(d.LD), char(d.GGIO), doIdx);
        end

        function ref = refGEN2_DO_setMag(app, doIdx)
            d = app.getDevice("GEN2");
            ref = sprintf('%s/%s.DO%d.setMag.f', char(d.LD), char(d.GGIO), doIdx);
        end

        function ref = refPV_DO_setMag(app, doIdx)
            d = app.getDevice("PV");
            ref = sprintf('%s/%s.DO%d.setMag.f', char(d.LD), char(d.GGIO), doIdx);
        end

        function [ok, msg] = connectAllIEDs(app)
            ok = false; msg = "";

            if isempty(app.pyiec)
                [okImp, msgImp] = app.importIecModule();
                if ~okImp
                    msg = msgImp;
                    return;
                end
            end

            if isempty(app.devices)
                app.initDevices();
            end

            msgs = strings(0);
            allOk = true;

            for i = 1:numel(app.devices)
                disp("Connecting to " + string(app.devices(i).name) + ...
                    " " + string(app.devices(i).host) + ":" + string(app.devices(i).port));

                [ok1, msg1] = app.connectOne(i);

                disp(" -> ok=" + string(ok1));
                if strlength(msg1) > 0
                    disp(" -> msg=" + msg1);
                end

                if ~ok1
                    allOk = false;
                    msgs(end+1) = msg1; %#ok<AGROW>
                end
            end

            app.isConnected = allOk;

            if ~allOk
                msg = strjoin(msgs, newline);
                ok = false;
                return;
            end

            ok = true;
        end


        function [ok, msg] = connectOne(app, i)
            ok = false; msg = "";

            d = app.devices(i);

            try
                con = app.pyiec.IedConnection_create();
                app.pyiec.IedConnection_connect(con, char(d.host), d.port);

                d.con = con;
                d.connected = true;
                app.devices(i) = d;

                ok = true;
                return;

            catch ME
                % IMPORTANT: print the raw error
                disp("CONNECT EXCEPTION for " + string(d.name) + ": " + string(ME.message));

                % cleanup best effort
                try
                    if ~isempty(d.con)
                        try app.pyiec.IedConnection_close(d.con); catch, end
                        try app.pyiec.IedConnection_destroy(d.con); catch, end
                    end
                catch
                end

                d.con = [];
                d.connected = false;
                app.devices(i) = d;

                msg = "Connect failed for " + string(d.name) + ...
                    " (" + string(d.host) + ":" + string(d.port) + "): " + string(ME.message);
            end
        end


        function disconnectAllIEDs(app)
            try
                if isa(app.pollTimer,'timer') && isvalid(app.pollTimer)
                    stop(app.pollTimer);
                end
            catch
            end

            if isempty(app.devices)
                app.isConnected = false;
                return;
            end

            for i = 1:numel(app.devices)
                app.disconnectOne(i);
            end

            app.isConnected = false;
        end

        function disconnectOne(app, i)
            try
                d = app.devices(i);
                if ~isempty(d.con)
                    try app.pyiec.IedConnection_close(d.con); catch, end
                    try app.pyiec.IedConnection_destroy(d.con); catch, end
                end
                d.con = [];
                d.connected = false;
                app.devices(i) = d;
            catch
            end
        end

        function readAllEditFieldsOnce(app)
            if ~app.isConnected
                disp("readAllEditFieldsOnce: not connected");
                return;
            end

            % Ensure uiMap exists
            if isempty(app.uiMap) || ~isfield(app.uiMap,'PCC1') || ~isfield(app.uiMap,'GEN1') || ~isfield(app.uiMap,'GEN2') || ~isfield(app.uiMap,'PV') 
                app.uiMap = app.buildUiMap();
            end

            FC_MX = app.pyiec.IEC61850_FC_MX;
            FC_ST = app.pyiec.IEC61850_FC_ST;

            % ---------- PCC1 ----------
            [conP, dP] = app.getCon("PCC1");
            if isempty(conP)
                disp("PCC1: no connection object");
            else
                disp("PCC1 connected. LD=" + string(dP.LD) + " GGIO=" + string(dP.GGIO));

                % Grid status: SPCSO1.stVal
                refS = app.refSPCSO_stVal("PCC1","SPCSO1");
                try
                    st_py = app.pyiec.IedConnection_readBooleanValue(conP, char(refS), FC_ST);
                    breakerOn = app.pyToBool(st_py);

                    % NOTE: you might want to invert this later depending on your model meaning
                    if breakerOn
                        app.uiMap.PCC1.GridStatus.Value = "Islanded";
                    else
                        app.uiMap.PCC1.GridStatus.Value = "Connected to Utility Grid";
                    end

                    disp("PCC1 OK ST " + string(refS) + " = " + string(breakerOn));
                catch ME
                    disp("PCC1 FAIL ST " + string(refS));
                    disp("  " + string(ME.message));
                end

                % AnIn1..8
                for idx = 1:8
                    refA = app.refAnIn("PCC1", idx);
                    try
                        v_py = app.pyiec.IedConnection_readFloatValue(conP, char(refA), FC_MX);
                        v = app.pyToDouble(v_py);

                        % containers.Map needs 2-step indexing
                        fld = app.uiMap.PCC1.AnIn(idx);
                        fld.Value = num2str(v);

                        disp("PCC1 OK MX " + string(refA) + " = " + string(v));
                    catch ME
                        disp("PCC1 FAIL MX " + string(refA));
                        disp("  " + string(ME.message));
                    end
                end
            end

            % ---------- GEN1 ----------
            [conG, dG] = app.getCon("GEN1");
            if isempty(conG)
                disp("GEN1: no connection object");
            else
                disp("GEN1 connected. LD=" + string(dG.LD) + " GGIO=" + string(dG.GGIO));

                % Breaker status: SPCSO1.stVal (may be wrong, but we’ll see immediately)
                refSg = app.refSPCSO_stVal("GEN1","SPCSO1");
                try
                    stg_py = app.pyiec.IedConnection_readBooleanValue(conG, char(refSg), FC_ST);
                    genClosed = app.pyToBool(stg_py);

                    if genClosed
                        app.uiMap.GEN1.BreakerStatus.Value = "Connected";
                    else
                        app.uiMap.GEN1.BreakerStatus.Value = "Disconnected";
                    end

                    disp("GEN1 OK ST " + string(refSg) + " = " + string(genClosed));
                catch ME
                    disp("GEN1 FAIL ST " + string(refSg));
                    disp("  " + string(ME.message));
                end

                % AnIn1..5
                for idx = 1:5
                    refAg = app.refAnIn("GEN1", idx);
                    try
                        v_py = app.pyiec.IedConnection_readFloatValue(conG, char(refAg), FC_MX);
                        v = app.pyToDouble(v_py);

                        % containers.Map needs 2-step indexing
                        fld = app.uiMap.GEN1.AnIn(idx);
                        fld.Value = num2str(v);

                        disp("GEN1 OK MX " + string(refAg) + " = " + string(v));
                    catch ME
                        disp("GEN1 FAIL MX " + string(refAg));
                        disp("  " + string(ME.message));
                    end
                end
            end
            % ---------- GEN2 ----------
            [conG, dG] = app.getCon("GEN2");
            if isempty(conG)
                disp("GEN2: no connection object");
            else
                disp("GEN2 connected. LD=" + string(dG.LD) + " GGIO=" + string(dG.GGIO));

                % Breaker status: SPCSO1.stVal (may be wrong, but we’ll see immediately)
                refSg = app.refSPCSO_stVal("GEN2","SPCSO1");
                try
                    stg_py = app.pyiec.IedConnection_readBooleanValue(conG, char(refSg), FC_ST);
                    genClosed = app.pyToBool(stg_py);

                    if genClosed
                        app.uiMap.GEN2.BreakerStatus.Value = "Connected";
                    else
                        app.uiMap.GEN2.BreakerStatus.Value = "Disconnected";
                    end

                    disp("GEN2 OK ST " + string(refSg) + " = " + string(genClosed));
                catch ME
                    disp("GEN2 FAIL ST " + string(refSg));
                    disp("  " + string(ME.message));
                end

                % AnIn1..5
                for idx = 1:5
                    refAg = app.refAnIn("GEN2", idx);
                    try
                        v_py = app.pyiec.IedConnection_readFloatValue(conG, char(refAg), FC_MX);
                        v = app.pyToDouble(v_py);

                        % containers.Map needs 2-step indexing
                        fld = app.uiMap.GEN2.AnIn(idx);
                        fld.Value = num2str(v);

                        disp("GEN2 OK MX " + string(refAg) + " = " + string(v));
                    catch ME
                        disp("GEN2 FAIL MX " + string(refAg));
                        disp("  " + string(ME.message));
                    end
                end

                % GEN2: AnIn6 -> DroopP slider, AnIn7 -> DroopQ slider (read-only indicators)
                app.updateSliderFromAnIn("GEN2", 6, app.uiMap.GEN2.ReadbackSliders.DroopP);
                app.updateSliderFromAnIn("GEN2", 7, app.uiMap.GEN2.ReadbackSliders.DroopQ);
            end

            % ---------- PV ----------
            [conG, dG] = app.getCon("PV");
            if isempty(conG)
                disp("PV: no connection object");
            else
                disp("PV connected. LD=" + string(dG.LD) + " GGIO=" + string(dG.GGIO));

                % Breaker status: SPCSO1.stVal (may be wrong, but we’ll see immediately)
                refSg = app.refSPCSO_stVal("PV","SPCSO1");
                try
                    stg_py = app.pyiec.IedConnection_readBooleanValue(conG, char(refSg), FC_ST);
                    PVClosed = app.pyToBool(stg_py);

                    if PVClosed
                        app.uiMap.PV.BreakerStatus.Value = "Connected";
                    else
                        app.uiMap.PV.BreakerStatus.Value = "Disconnected";
                    end

                    disp("PV OK ST " + string(refSg) + " = " + string(PVClosed));
                catch ME
                    disp("PV FAIL ST " + string(refSg));
                    disp("  " + string(ME.message));
                end

                % AnIn1..5
                for idx = 1:5
                    refAg = app.refAnIn("PV", idx);
                    try
                        v_py = app.pyiec.IedConnection_readFloatValue(conG, char(refAg), FC_MX);
                        v = app.pyToDouble(v_py);

                        % containers.Map needs 2-step indexing
                        fld = app.uiMap.PV.AnIn(idx);
                        fld.Value = num2str(v);

                        disp("PV OK MX " + string(refAg) + " = " + string(v));
                    catch ME
                        disp("PV FAIL MX " + string(refAg));
                        disp("  " + string(ME.message));
                    end
                end
            end
        end

        function pollOnce(app)
            if ~app.isConnected
                return;
            end

            if app.iecBusy
                return;
            end
            app.iecBusy = true;
            c = onCleanup(@()app.releaseBusy()); %#ok<NASGU>

            try
                app.readAllEditFieldsOnce();
            catch ME
                uialert(app.UIFigure, "pollOnce failed:" + newline + string(ME.message), "IEC61850");
            end
        end


        function releaseBusy(app)
            app.iecBusy = false;
        end

        function [ok, msg] = operateControl(app, devName, lnName, desiredOn)
            % Generic boolean control (SPCSO*)
            ok = false; msg = "";

            [con, ~] = app.getCon(devName);
            if ~app.isConnected || isempty(con)
                msg = "Not connected to " + string(devName);
                return;
            end

            if app.iecBusy
                msg = "IEC busy (poll/operate overlap). Try again.";
                return;
            end
            app.iecBusy = true;
            c = onCleanup(@()app.releaseBusy()); %#ok<NASGU>

            control = [];
            ctlVal = [];

            try
                ctrlRef = char(app.refControlObject(devName, lnName));
                control = app.pyiec.ControlObjectClient_create(ctrlRef, con);
                if isempty(control)
                    msg = "ControlObjectClient_create returned NULL for: " + string(ctrlRef);
                    return;
                end

                ctlVal = app.pyiec.MmsValue_newBoolean(logical(desiredOn));

                ok_py = app.pyiec.ControlObjectClient_operate(control, ctlVal, int32(0));
                ok = app.pyToBool(ok_py);

                if ~ok
                    msg = "Operate failed.";
                    try
                        if ismethod(app.pyiec, "ControlObjectClient_getLastError")
                            last = app.pyiec.ControlObjectClient_getLastError(control);
                            msg = msg + " lastError=" + string(last);
                        end
                    catch
                    end
                end

            catch ME
                msg = "Operate exception: " + string(ME.message);
            end

            try if ~isempty(ctlVal), app.pyiec.MmsValue_delete(ctlVal); end; catch, end
            try if ~isempty(control), app.pyiec.ControlObjectClient_destroy(control); end; catch, end
        end

        function [ok, msg] = writeGEN1Setpoint(app, doIdx, value, scale)
            % Write GEN1 DOx.setMag.f using gen1.txt strategy (direct write OR writeObject fallback)
            ok = false; msg = "";

            [conG, ~] = app.getCon("GEN1");
            if ~app.isConnected || isempty(conG)
                msg = "Not connected to GEN1";
                return;
            end

            if app.iecBusy
                msg = "IEC busy (poll/write overlap). Try again.";
                return;
            end
            app.iecBusy = true;
            c = onCleanup(@()app.releaseBusy()); %#ok<NASGU>

            try
                FC_SP = app.pyiec.IEC61850_FC_SP;

                ref = app.refGEN1_DO_setMag(doIdx);
                vScaled = double(value) * double(scale);

                [ok, msg] = app.writeFloat61850(conG, ref, FC_SP, vScaled);
                if ~ok
                    return;
                end

                % Optional readback (helps confirm it really latched)
                try
                    rb_py = app.pyiec.IedConnection_readFloatValue(conG, char(ref), FC_SP);
                    rb = app.pyToDouble(rb_py);
                    disp("GEN1 SP READBACK " + string(ref) + " = " + string(rb));
                catch
                    % ignore readback errors
                end

            catch ME
                ok = false;
                msg = "Write exception: " + string(ME.message);
            end
        end

        function [ok, msg] = writeGEN2Setpoint(app, doIdx, value, scale)
            % Write GEN2 DOx.setMag.f using gen1.txt strategy (direct write OR writeObject fallback)
            ok = false; msg = "";

            [conG, ~] = app.getCon("GEN2");
            if ~app.isConnected || isempty(conG)
                msg = "Not connected to GEN2";
                return;
            end

            if app.iecBusy
                msg = "IEC busy (poll/write overlap). Try again.";
                return;
            end
            app.iecBusy = true;
            c = onCleanup(@()app.releaseBusy()); %#ok<NASGU>

            try
                FC_SP = app.pyiec.IEC61850_FC_SP;

                ref = app.refGEN2_DO_setMag(doIdx);
                vScaled = double(value) * double(scale);

                [ok, msg] = app.writeFloat61850(conG, ref, FC_SP, vScaled);
                if ~ok
                    return;
                end

                % Optional readback (helps confirm it really latched)
                try
                    rb_py = app.pyiec.IedConnection_readFloatValue(conG, char(ref), FC_SP);
                    rb = app.pyToDouble(rb_py);
                    disp("GEN2 SP READBACK " + string(ref) + " = " + string(rb));
                catch
                    % ignore readback errors
                end

            catch ME
                ok = false;
                msg = "Write exception: " + string(ME.message);
            end
        end

        function [ok, msg] = writePVSetpoint(app, doIdx, value, scale)
            % Write PV DOx.setMag.f using gen1.txt strategy (direct write OR writeObject fallback)
            ok = false; msg = "";

            [conG, ~] = app.getCon("PV");
            if ~app.isConnected || isempty(conG)
                msg = "Not connected to PV";
                return;
            end

            if app.iecBusy
                msg = "IEC busy (poll/write overlap). Try again.";
                return;
            end
            app.iecBusy = true;
            c = onCleanup(@()app.releaseBusy()); %#ok<NASGU>

            try
                FC_SP = app.pyiec.IEC61850_FC_SP;

                ref = app.refPV_DO_setMag(doIdx);
                vScaled = double(value) * double(scale);

                [ok, msg] = app.writeFloat61850(conG, ref, FC_SP, vScaled);
                if ~ok
                    return;
                end

                % Optional readback (helps confirm it really latched)
                try
                    rb_py = app.pyiec.IedConnection_readFloatValue(conG, char(ref), FC_SP);
                    rb = app.pyToDouble(rb_py);
                    disp("PV SP READBACK " + string(ref) + " = " + string(rb));
                catch
                    % ignore readback errors
                end

            catch ME
                ok = false;
                msg = "Write exception: " + string(ME.message);
            end
        end

        function updateSliderFromAnIn(app, devName, anInIdx, sliderHandle)
            try
                FC_MX = app.pyiec.IEC61850_FC_MX;

                [con, ~] = app.getCon(devName);
                if isempty(con)
                    disp(devName + ": no connection object (updateSliderFromAnIn)");
                    return;
                end

                ref = app.refAnIn(devName, anInIdx);
                v_py = app.pyiec.IedConnection_readFloatValue(con, char(ref), FC_MX);
                v = app.pyToDouble(v_py);

                % Ensure scalar finite double
                if ~isscalar(v) || ~isfinite(v)
                    disp(devName + " AnIn" + anInIdx + " not scalar/finite: " + string(v));
                    return;
                end
                v = double(v);

                % Limits should be [min max]
                lim = sliderHandle.Limits;
                if ~isnumeric(lim) || numel(lim) ~= 2 || ~all(isfinite(lim))
                    disp(devName + " slider limits invalid for AnIn" + anInIdx + ": " + string(lim));
                    return;
                end
                lim = double(lim(:).');   % force 1x2

                % Clamp
                v = min(max(v, lim(1)), lim(2));

                % Update
                sliderHandle.Value = v;

                % Optional debug
                % disp(devName + " AnIn" + anInIdx + " -> slider = " + string(v) + " limits=[" + string(lim(1)) + "," + string(lim(2)) + "]");
            catch ME
                disp(devName + " FAIL AnIn" + anInIdx + "->slider: " + string(ME.message));
            end
        end


        function v = pyToDouble(~, x)
            % Convert Python return types into a scalar double.
            % Handles cases where SWIG returns (value, err) or [value err].

            % 1) unwrap common Python containers: tuple/list
            try
                if isa(x, "py.tuple") || isa(x, "py.list")
                    if int64(py.len(x)) >= 1
                        x = x{1};   % take first element = actual value
                    end
                end
            catch
            end

            % 2) unwrap MATLAB numeric vectors (e.g. [10 0]) - take first element
            try
                if isnumeric(x) && numel(x) >= 1
                    x = x(1);
                end
            catch
            end

            % 3) direct numeric conversion
            try
                v = double(x);
                if isnumeric(v) && numel(v) >= 1
                    v = v(1); % force scalar
                end
                return;
            catch
            end

            % 4) parse from string as fallback
            s = string(x);

            % If string array, take first element
            if numel(s) >= 1
                s = s(1);
            end

            tok = regexp(s, '[-+]?\d*\.?\d+([eE][-+]?\d+)?', 'match', 'once');
            if isempty(tok)
                v = NaN;
            else
                v = str2double(tok);
            end
        end

        function b = pyToBool(~, x)
            try
                if islogical(x)
                    b = logical(x(1));
                    return;
                end
            catch
            end
            try
                if isnumeric(x)
                    b = logical(x(1));
                    return;
                end
            catch
            end
            s = lower(string(x));
            b = any(s == "true") || any(s == "1");
        end

        function tf = pyHasAttr(~, pymod, attrName)
            % MATLAB-safe hasattr() for Python modules/objects
            try
                tf = logical(py.hasattr(pymod, attrName));
            catch
                tf = false;
            end
        end

        function [ok, msg] = writeFloat61850(app, con, ref, fc, value_f)
            % Mirrors gen1.txt write_float():
            % 1) Try IedConnection_writeFloatValue
            % 2) Fallback to IedConnection_writeObject + MmsValue_newFloat
            ok = false;
            msg = "";

            lastErr = "";

            % 1) direct float write
            if app.pyHasAttr(app.pyiec, "IedConnection_writeFloatValue")
                try
                    app.pyiec.IedConnection_writeFloatValue(con, char(ref), fc, double(value_f));
                    ok = true;
                    msg = "writeFloatValue OK";
                    return;
                catch ME
                    lastErr = string(ME.message);
                end
            end

            % 2) fallback: writeObject(MmsValue_newFloat)
            if app.pyHasAttr(app.pyiec, "IedConnection_writeObject") && app.pyHasAttr(app.pyiec, "MmsValue_newFloat")
                m = [];
                try
                    m = app.pyiec.MmsValue_newFloat(double(value_f));
                    app.pyiec.IedConnection_writeObject(con, char(ref), fc, m);
                    ok = true;
                    msg = "writeObject OK";
                    return;
                catch ME
                    lastErr = string(ME.message);
                    finally
                    try
                        if ~isempty(m) && app.pyHasAttr(app.pyiec, "MmsValue_delete")
                            app.pyiec.MmsValue_delete(m);
                        end
                    catch
                    end
                end
            end

            ok = false;
            msg = "write failed: " + lastErr;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectToIEDsButton
        function ConnectToIEDsButtonPushed(app, event)
            disp("=== Connect button pressed ===");

            app.ensureTimer();

            [ok, msg] = app.connectAllIEDs();
            disp("connectAllIEDs ok = " + string(ok));
            if strlength(msg) > 0
                disp("connectAllIEDs msg: " + msg);
            end

            % Dump connection state
            if ~isempty(app.devices)
                for k = 1:numel(app.devices)
                    d = app.devices(k);
                    disp("DEV " + string(d.name) + ...
                        " connected=" + string(d.connected) + ...
                        " conEmpty=" + string(isempty(d.con)));
                end
            else
                disp("devices is EMPTY after connectAllIEDs");
            end

            if ~ok
                uialert(app.UIFigure, msg, "IEC61850");
                return;
            end

            try
                if isa(app.pollTimer,'timer') && isvalid(app.pollTimer)
                    start(app.pollTimer);
                end
            catch ME
                uialert(app.UIFigure, "Timer start failed: " + string(ME.message), "IEC61850");
            end

            app.ConnectToIEDsButton.Enable = "off";
            app.DisconnectFromIEDsButton.Enable = "on";

            app.readAllEditFieldsOnce();   % the debug read
        end

        % Button pushed function: DisconnectFromIEDsButton
        function DisconnectFromIEDsButtonPushed(app, event)
            app.disconnectAllIEDs();

            app.ConnectToIEDsButton.Enable = "on";
            app.DisconnectFromIEDsButton.Enable = "off";

            % optional clear UI
            try
                app.uiMap = app.buildUiMap();

                for idx = 1:8
                    try app.uiMap.PCC1.AnIn(idx).Value = ""; catch, end
                end
                try app.uiMap.PCC1.GridStatus.Value = ""; catch, end

                for idx = 1:5
                    try app.uiMap.GEN1.AnIn(idx).Value = ""; catch, end
                end
                try app.uiMap.GEN1.BreakerStatus.Value = ""; catch, end

                app.inSwitchCallback = true;
                try app.PCCBreakerSwitch_2.Value = "Disconnect From Utility Grid"; catch, end
                try app.GEN1BreakerSwitchA.Value = "Disconnect From Bus"; catch, end
                app.inSwitchCallback = false;
            catch
            end
        end

        % Value changed function: GEN1BreakerSwitchA
        function GEN1BreakerSwitchAValueChanged(app, event)
            if app.inSwitchCallback, return; end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IEDs.", "IEC61850");
                app.inSwitchCallback = true;
                try app.GEN1BreakerSwitchA.Value = "Disconnect From Bus"; catch, end
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = (string(app.GEN1BreakerSwitchA.Value) == "Connect to Bus");

            % From your server comment: CBG1 uses SPCSO1 -> assume control object is SPCSO1
            [ok, msg] = app.operateControl("GEN1","SPCSO1", desiredOn);
            if ~ok
                uialert(app.UIFigure, msg, "Operate failed");
                app.inSwitchCallback = true;
                if desiredOn
                    app.GEN1BreakerSwitchA.Value = "Disconnect From Bus";
                else
                    app.GEN1BreakerSwitchA.Value = "Connect to Bus";
                end
                app.inSwitchCallback = false;
                return;
            end

            app.pollOnce();
        end

        % Value changed function: PCCBreakerSwitch_2
        function PCCBreakerSwitch_2ValueChanged(app, event)
            if app.inSwitchCallback
                return;
            end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IEDs.", "IEC61850");
                app.inSwitchCallback = true;
                try app.PCCBreakerSwitch_2.Value = "Disconnect From Utility Grid"; catch, end
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = (string(app.PCCBreakerSwitch_2.Value) == "Connect to Utility Grid");

            % PCC control object is SPCSO2
            [ok, msg] = app.operateControl("PCC1","SPCSO2", desiredOn);

            if ~ok
                uialert(app.UIFigure, msg, "Operate failed");
                app.inSwitchCallback = true;
                if desiredOn
                    app.PCCBreakerSwitch_2.Value = "Disconnect From Utility Grid";
                else
                    app.PCCBreakerSwitch_2.Value = "Connect to Utility Grid";
                end
                app.inSwitchCallback = false;
                return;
            end

            app.pollOnce();
        end

        % Value changed function: PrefG1Slider
        function PrefG1SliderValueChanged(app, event)
            % DO1 -> PrefG1
            [ok, msg] = app.writeGEN1Setpoint(1, app.PrefG1Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("Pref write FAIL: " + msg);
            end
        end

        % Value changed function: QrefG1Slider
        function QrefG1SliderValueChanged(app, event)
            % DO3 -> QrefG1
            [ok, msg] = app.writeGEN1Setpoint(3, app.QrefG1Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("Qref write FAIL: " + msg);
            end
        end

        % Value changed function: DroopPG1Slider
        function DroopPG1SliderValueChanged(app, event)
            % DO2 -> DroopPG1
            [ok, msg] = app.writeGEN1Setpoint(2, app.DroopPG1Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("DroopP write FAIL: " + msg);
            end
        end

        % Value changed function: DroopQG1Slider
        function DroopQG1SliderValueChanged(app, event)
            % DO4 -> DroopQG1
            [ok, msg] = app.writeGEN1Setpoint(4, app.DroopQG1Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("DroopQ write FAIL: " + msg);
            end
        end

        % Value changed function: GEN2BreakerSwitchA
        function GEN2BreakerSwitchAValueChanged(app, event)
            if app.inSwitchCallback, return; end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IEDs.", "IEC61850");
                app.inSwitchCallback = true;
                try app.GEN2BreakerSwitchA.Value = "Disconnect From Bus"; catch, end
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = (string(app.GEN2BreakerSwitchA.Value) == "Connect to Bus");

            % From your server comment: CBG1 uses SPCSO1 -> assume control object is SPCSO1
            [ok, msg] = app.operateControl("GEN2","SPCSO1", desiredOn);
            if ~ok
                uialert(app.UIFigure, msg, "Operate failed");
                app.inSwitchCallback = true;
                if desiredOn
                    app.GEN2BreakerSwitchA.Value = "Disconnect From Bus";
                else
                    app.GEN2BreakerSwitchA.Value = "Connect to Bus";
                end
                app.inSwitchCallback = false;
                return;
            end

            app.pollOnce();
        end

        % Value changed function: PrefG2Slider
        function PrefG2SliderValueChanged(app, event)
            % DO1 -> PrefG1
            [ok, msg] = app.writeGEN2Setpoint(1, app.PrefG2Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("Qref write FAIL: " + msg);
            end
        end

        % Value changed function: QrefG2Slider
        function QrefG2SliderValueChanged(app, event)
            % DO1 -> PrefG1
            [ok, msg] = app.writeGEN2Setpoint(3, app.QrefG2Slider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("Qref write FAIL: " + msg);
            end
        end

        % Value changed function: PVBreakerSwitchA
        function PVBreakerSwitchAValueChanged(app, event)
            if app.inSwitchCallback, return; end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IEDs.", "IEC61850");
                app.inSwitchCallback = true;
                try app.PVBreakerSwitchA.Value = "Disconnect From Bus"; catch, end
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = (string(app.PVBreakerSwitchA.Value) == "Connect to Bus");

            % From your server comment: CBG1 uses SPCSO1 -> assume control object is SPCSO1
            [ok, msg] = app.operateControl("PV","SPCSO1", desiredOn);
            if ~ok
                uialert(app.UIFigure, msg, "Operate failed");
                app.inSwitchCallback = true;
                if desiredOn
                    app.PVBreakerSwitchA.Value = "Disconnect From Bus";
                else
                    app.PVBreakerSwitchA.Value = "Connect to Bus";
                end
                app.inSwitchCallback = false;
                return;
            end

            app.pollOnce();
        end

        % Value changed function: CurtailmentEnableBreakerSwitchA
        function CurtailmentEnableBreakerSwitchAValueChanged(app, event)
            if app.inSwitchCallback, return; end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IEDs.", "IEC61850");
                app.inSwitchCallback = true;
                try app.CurtailmentEnableBreakerSwitchA.Value = "Disconnect From Bus"; catch, end
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = (string(app.CurtailmentEnableBreakerSwitchA.Value) == "Connect to Bus");

            % From your server comment: CBG1 uses SPCSO1 -> assume control object is SPCSO1
            [ok, msg] = app.operateControl("PV","SPCSO2", desiredOn);
            if ~ok
                uialert(app.UIFigure, msg, "Operate failed");
                app.inSwitchCallback = true;
                if desiredOn
                    app.CurtailmentEnableBreakerSwitchA.Value = "Disconnect From Bus";
                else
                    app.CurtailmentEnableBreakerSwitchA.Value = "Connect to Bus";
                end
                app.inSwitchCallback = false;
                return;
            end

            app.pollOnce();
        end

        % Value changed function: CurtailmentSet_PVSlider
        function CurtailmentSet_PVSliderValueChanged(app, event)
            % DO1 -> CurtailmentSet_PV
            [ok, msg] = app.writePVSetpoint(1, app.CurtailmentSet_PVSlider.Value, 1.0);
            if ~ok && strlength(msg) > 0
                disp("CurtailmentSet_PV write FAIL: " + msg);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectToIEDsButton = uibutton(app.UIFigure, 'push');
            app.ConnectToIEDsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToIEDsButtonPushed, true);
            app.ConnectToIEDsButton.Text = 'Connect To IEDs';
            app.DisconnectFromIEDsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectFromIEDsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectFromIEDsButtonPushed, true);
            app.DisconnectFromIEDsButton.Text = 'Disconnect From IEDs';
            app.vUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.vUtilityGridEditFieldLabel.Text = 'vUtilityGrid';
            app.vUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.vMicroGridEditFieldLabel = uilabel(app.UIFigure);
            app.vMicroGridEditFieldLabel.Text = 'vMicroGrid';
            app.vMicroGridEditField = uieditfield(app.UIFigure, 'text');
            app.iUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.iUtilityGridEditFieldLabel.Text = 'iUtilityGrid';
            app.iUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.iMicrogridEditField_2Label = uilabel(app.UIFigure);
            app.iMicrogridEditField_2Label.Text = 'iMicrogrid';
            app.iMicrogridEditField_2 = uieditfield(app.UIFigure, 'text');
            app.phiUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.phiUtilityGridEditFieldLabel.Text = 'phiUtilityGrid';
            app.phiUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.phiMicroGridEditFieldLabel = uilabel(app.UIFigure);
            app.phiMicroGridEditFieldLabel.Text = 'phiMicroGrid';
            app.phiMicroGridEditField = uieditfield(app.UIFigure, 'text');
            app.PGridEditFieldLabel = uilabel(app.UIFigure);
            app.PGridEditFieldLabel.Text = 'PGrid';
            app.PGridEditField = uieditfield(app.UIFigure, 'text');
            app.QGridEditFieldLabel = uilabel(app.UIFigure);
            app.QGridEditFieldLabel.Text = 'QGrid';
            app.QGridEditField = uieditfield(app.UIFigure, 'text');
            app.GridStatusEditFieldLabel = uilabel(app.UIFigure);
            app.GridStatusEditFieldLabel.Text = 'Grid Status';
            app.GridStatusEditField = uieditfield(app.UIFigure, 'text');
            app.GEN1BreakerSwitchALabel = uilabel(app.UIFigure);
            app.GEN1BreakerSwitchALabel.Text = 'GEN1 Breaker SwitchA';
            app.GEN1BreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.GEN1BreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.GEN1BreakerSwitchA.ValueChangedFcn = createCallbackFcn(app, @GEN1BreakerSwitchAValueChanged, true);
            app.GEN1BreakerSwitchA.Value = 'Disconnect From Bus';
            app.P_gen1EditFieldLabel = uilabel(app.UIFigure);
            app.P_gen1EditFieldLabel.Text = 'P_gen1';
            app.P_gen1EditField = uieditfield(app.UIFigure, 'text');
            app.Q_gen1_puEditFieldLabel = uilabel(app.UIFigure);
            app.Q_gen1_puEditFieldLabel.Text = 'Q_gen1_pu';
            app.Q_gen1_puEditField = uieditfield(app.UIFigure, 'text');
            app.P_gen1_puEditFieldLabel = uilabel(app.UIFigure);
            app.P_gen1_puEditFieldLabel.Text = 'P_gen1_pu';
            app.P_gen1_puEditField = uieditfield(app.UIFigure, 'text');
            app.freq_gen1_puEditFieldLabel = uilabel(app.UIFigure);
            app.freq_gen1_puEditFieldLabel.Text = 'freq_gen1_pu';
            app.freq_gen1_puEditField = uieditfield(app.UIFigure, 'text');
            app.V_gen1_puEditFieldLabel = uilabel(app.UIFigure);
            app.V_gen1_puEditFieldLabel.Text = 'V_gen1_pu';
            app.V_gen1_puEditField = uieditfield(app.UIFigure, 'text');
            app.G1_BreakerStatusEditFieldLabel = uilabel(app.UIFigure);
            app.G1_BreakerStatusEditFieldLabel.Text = 'G1_BreakerStatus';
            app.G1_BreakerStatusEditField = uieditfield(app.UIFigure, 'text');
            app.PCCBreakerSwitch_2Label = uilabel(app.UIFigure);
            app.PCCBreakerSwitch_2Label.Text = 'PCC Breaker Switch';
            app.PCCBreakerSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.PCCBreakerSwitch_2.Items = {'Disconnect From Utility Grid', 'Connect to Utility Grid'};
            app.PCCBreakerSwitch_2.ValueChangedFcn = createCallbackFcn(app, @PCCBreakerSwitch_2ValueChanged, true);
            app.PCCBreakerSwitch_2.Value = 'Disconnect From Utility Grid';
            app.PrefG1SliderLabel = uilabel(app.UIFigure);
            app.PrefG1SliderLabel.Text = 'PrefG1';
            app.PrefG1Slider = uislider(app.UIFigure);
            app.PrefG1Slider.ValueChangedFcn = createCallbackFcn(app, @PrefG1SliderValueChanged, true);
            app.QrefG1SliderLabel = uilabel(app.UIFigure);
            app.QrefG1SliderLabel.Text = 'QrefG1';
            app.QrefG1Slider = uislider(app.UIFigure);
            app.QrefG1Slider.ValueChangedFcn = createCallbackFcn(app, @QrefG1SliderValueChanged, true);
            app.DroopPG1SliderLabel = uilabel(app.UIFigure);
            app.DroopPG1SliderLabel.Text = 'DroopPG1';
            app.DroopPG1Slider = uislider(app.UIFigure);
            app.DroopPG1Slider.ValueChangedFcn = createCallbackFcn(app, @DroopPG1SliderValueChanged, true);
            app.DroopQG1SliderLabel = uilabel(app.UIFigure);
            app.DroopQG1SliderLabel.Text = 'DroopQG1';
            app.DroopQG1Slider = uislider(app.UIFigure);
            app.DroopQG1Slider.ValueChangedFcn = createCallbackFcn(app, @DroopQG1SliderValueChanged, true);
            app.P_gen2EditFieldLabel = uilabel(app.UIFigure);
            app.P_gen2EditFieldLabel.Text = 'P_gen2';
            app.P_gen2EditField = uieditfield(app.UIFigure, 'text');
            app.Q_gen2_puEditFieldLabel = uilabel(app.UIFigure);
            app.Q_gen2_puEditFieldLabel.Text = 'Q_gen2_pu';
            app.Q_gen2_puEditField = uieditfield(app.UIFigure, 'text');
            app.P_gen2_puEditFieldLabel = uilabel(app.UIFigure);
            app.P_gen2_puEditFieldLabel.Text = 'P_gen2_pu';
            app.P_gen2_puEditField = uieditfield(app.UIFigure, 'text');
            app.freq_gen2_puEditFieldLabel = uilabel(app.UIFigure);
            app.freq_gen2_puEditFieldLabel.Text = 'freq_gen2_pu';
            app.freq_gen2_puEditField = uieditfield(app.UIFigure, 'text');
            app.V_gen2_puEditFieldLabel = uilabel(app.UIFigure);
            app.V_gen2_puEditFieldLabel.Text = 'V_gen2_pu';
            app.V_gen2_puEditField = uieditfield(app.UIFigure, 'text');
            app.G2_BreakerStatusEditFieldLabel = uilabel(app.UIFigure);
            app.G2_BreakerStatusEditFieldLabel.Text = 'G2_BreakerStatus';
            app.G2_BreakerStatusEditField = uieditfield(app.UIFigure, 'text');
            app.GEN2BreakerSwitchALabel = uilabel(app.UIFigure);
            app.GEN2BreakerSwitchALabel.Text = 'GEN2 Breaker SwitchA';
            app.GEN2BreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.GEN2BreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.GEN2BreakerSwitchA.ValueChangedFcn = createCallbackFcn(app, @GEN2BreakerSwitchAValueChanged, true);
            app.GEN2BreakerSwitchA.Value = 'Disconnect From Bus';
            app.PrefG2SliderLabel = uilabel(app.UIFigure);
            app.PrefG2SliderLabel.Text = 'PrefG2';
            app.PrefG2Slider = uislider(app.UIFigure);
            app.PrefG2Slider.ValueChangedFcn = createCallbackFcn(app, @PrefG2SliderValueChanged, true);
            app.QrefG2SliderLabel = uilabel(app.UIFigure);
            app.QrefG2SliderLabel.Text = 'QrefG2';
            app.QrefG2Slider = uislider(app.UIFigure);
            app.QrefG2Slider.ValueChangedFcn = createCallbackFcn(app, @QrefG2SliderValueChanged, true);
            app.DroopPG2SliderLabel = uilabel(app.UIFigure);
            app.DroopPG2SliderLabel.Text = 'DroopPG2';
            app.DroopPG2Slider = uislider(app.UIFigure);
            app.DroopQG2SliderLabel = uilabel(app.UIFigure);
            app.DroopQG2SliderLabel.Text = 'DroopQG2';
            app.DroopQG2Slider = uislider(app.UIFigure);
            app.P_PVEditFieldLabel = uilabel(app.UIFigure);
            app.P_PVEditFieldLabel.Text = 'P_PV';
            app.P_PVEditField = uieditfield(app.UIFigure, 'text');
            app.duty_cycle_PVEditFieldLabel = uilabel(app.UIFigure);
            app.duty_cycle_PVEditFieldLabel.Text = 'duty_cycle_PV';
            app.duty_cycle_PVEditField = uieditfield(app.UIFigure, 'text');
            app.V_PVEditFieldLabel = uilabel(app.UIFigure);
            app.V_PVEditFieldLabel.Text = 'V_PV';
            app.V_PVEditField = uieditfield(app.UIFigure, 'text');
            app.Irradiance_PVEditFieldLabel = uilabel(app.UIFigure);
            app.Irradiance_PVEditFieldLabel.Text = 'Irradiance_PV';
            app.Irradiance_PVEditField = uieditfield(app.UIFigure, 'text');
            app.I_PVEditFieldLabel = uilabel(app.UIFigure);
            app.I_PVEditFieldLabel.Text = 'I_PV';
            app.I_PVEditField = uieditfield(app.UIFigure, 'text');
            app.PV_BreakerStatusEditFieldLabel = uilabel(app.UIFigure);
            app.PV_BreakerStatusEditFieldLabel.Text = 'PV_BreakerStatus';
            app.PV_BreakerStatusEditField = uieditfield(app.UIFigure, 'text');
            app.CurtailmentEnableBreakerSwitchALabel = uilabel(app.UIFigure);
            app.CurtailmentEnableBreakerSwitchALabel.Text = 'CurtailmentEnable Breaker SwitchA';
            app.CurtailmentEnableBreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.CurtailmentEnableBreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.CurtailmentEnableBreakerSwitchA.ValueChangedFcn = createCallbackFcn(app, @CurtailmentEnableBreakerSwitchAValueChanged, true);
            app.CurtailmentEnableBreakerSwitchA.Value = 'Disconnect From Bus';
            app.CurtailmentSet_PVSliderLabel = uilabel(app.UIFigure);
            app.CurtailmentSet_PVSliderLabel.Text = 'CurtailmentSet_PV';
            app.CurtailmentSet_PVSlider = uislider(app.UIFigure);
            app.CurtailmentSet_PVSlider.ValueChangedFcn = createCallbackFcn(app, @CurtailmentSet_PVSliderValueChanged, true);
            app.PVBreakerSwitchA_2Label = uilabel(app.UIFigure);
            app.PVBreakerSwitchA_2Label.Text = 'PV Breaker SwitchA';
            app.PVBreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.PVBreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.PVBreakerSwitchA.ValueChangedFcn = createCallbackFcn(app, @PVBreakerSwitchAValueChanged, true);
            app.PVBreakerSwitchA.Value = 'Disconnect From Bus';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '39-399455_generator-symbol.png');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, '39-399455_generator-symbol.png');
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'solar pv.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'full cell.png');
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'bess.jpg');
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'utility.png');
            app.PV_BreakerStatusEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PV_BreakerStatusEditFieldLabel_2.Text = 'FC_BreakerStatus';
            app.PV_BreakerStatusEditField_2 = uieditfield(app.UIFigure, 'text');
            app.P_FVEditFieldLabel = uilabel(app.UIFigure);
            app.P_FVEditFieldLabel.Text = 'P_FV';
            app.P_FVEditField = uieditfield(app.UIFigure, 'text');
            app.FCBreakerSwitchALabel = uilabel(app.UIFigure);
            app.FCBreakerSwitchALabel.Text = 'FC Breaker SwitchA';
            app.FCBreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.FCBreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.FCBreakerSwitchA.Value = 'Disconnect From Bus';
            app.PV_BreakerStatusEditFieldLabel_3 = uilabel(app.UIFigure);
            app.PV_BreakerStatusEditFieldLabel_3.Text = 'BESS_BreakerStatus';
            app.PV_BreakerStatusEditField_3 = uieditfield(app.UIFigure, 'text');
            app.SOC_BESSEditFieldLabel = uilabel(app.UIFigure);
            app.SOC_BESSEditFieldLabel.Text = 'SOC_BESS';
            app.SOC_BESSEditField = uieditfield(app.UIFigure, 'text');
            app.BESSBreakerSwitchALabel = uilabel(app.UIFigure);
            app.BESSBreakerSwitchALabel.Text = 'BESS Breaker SwitchA';
            app.BESSBreakerSwitchA = uiswitch(app.UIFigure, 'slider');
            app.BESSBreakerSwitchA.Items = {'Disconnect From Bus', 'Connect to Bus'};
            app.BESSBreakerSwitchA.Value = 'Disconnect From Bus';
            app.P_BESSEditFieldLabel = uilabel(app.UIFigure);
            app.P_BESSEditFieldLabel.Text = 'P_BESS';
            app.P_BESSEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end