classdef scadav1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        Disconnectfromied1Button  matlab.ui.control.Button
        Connecttoied1Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        iec % Description
        con1 % Description
      
     
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Connecttoied1Button
        function Connecttoied1ButtonPushed(app, event)
   try
        app.iec = py.importlib.import_module('pyiec61850');

        app.con1 = app.iec.IedConnection_create();
        err = app.iec.IedConnection_connect(app.con1, '192.168.1.201', int32(102));


        if err == app.iec.IED_ERROR_OK
            uialert(app.UIFigure, 'Connection to IED1 successful!', 'IEC61850');
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end


        end

        % Button pushed function: Disconnectfromied1Button
        function Disconnectfromied1ButtonPushed(app, event)

    if ~isempty(app.con1)
        app.iec.IedConnection_destroy(app.con1);
        app.con1 = [];
    end
    uialert(app.UIFigure, 'Disconnected from IEDServer1', 'IEC61850');


        end

        % Callback function: not associated with a component
        function DesiredVoltageEditFieldValueChanged(app, event)
        
        end

        % Callback function: not associated with a component
        function SwitchValueChanged(app, event)
  
        end

        % Callback function: not associated with a component
        function Connecttoied2ButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function Connecttoied3ButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function Disconnectfromied2ButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function Disconnectfromied3ButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Connecttoied1Button = uibutton(app.UIFigure, 'push');
            app.Connecttoied1Button.ButtonPushedFcn = createCallbackFcn(app, @Connecttoied1ButtonPushed, true);
            app.Connecttoied1Button.Text = 'Connect to ied1';
            app.Disconnectfromied1Button = uibutton(app.UIFigure, 'push');
            app.Disconnectfromied1Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnectfromied1ButtonPushed, true);
            app.Disconnectfromied1Button.Text = 'Disconnect from ied1';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scadav1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end